classdef ISSI_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GoFocus                         matlab.ui.control.Button
        FocusIncrementEditFieldLabel    matlab.ui.control.Label
        FocusIncrementEditField         matlab.ui.control.NumericEditField
        LeftFocusButton                 matlab.ui.control.Button
        RightFocusButton                matlab.ui.control.Button
        FocusReadbackEditFieldLabel     matlab.ui.control.Label
        FocusReadbackEditField          matlab.ui.control.NumericEditField
        ApertureReadbackEditFieldLabel  matlab.ui.control.Label
        ApertureReadbackEditField       matlab.ui.control.NumericEditField
        FocusValueEditFieldLabel        matlab.ui.control.Label
        FocusValueEditField             matlab.ui.control.NumericEditField
        ISSILensControllerLabel         matlab.ui.control.Label
        LeftApertureButton              matlab.ui.control.Button
        RightApertureButton             matlab.ui.control.Button
        PresetsDropDownLabel            matlab.ui.control.Label
        PresetsDropDown                 matlab.ui.control.DropDown
        GoPresetButton                  matlab.ui.control.Button
        ContextMenu                     matlab.ui.container.ContextMenu
        EditPresetsMenu                 matlab.ui.container.Menu
    end

    
    properties (Access = private)
        CameraFocusValue % Description
        CameraAperture
        FocusPresets
        IOC_name
        Panel_name
    end
    
    properties (Access = public)
        ISSItimer
        BooleanMonitorValues
        ISSIPVs
    end
    
    methods (Access = private)
        function CheckMonitors(app)
            app.BooleanMonitorValues = lcaNewMonitorValue(app.ISSIPVs(1:2)) ;
            for i = 1:length(app.BooleanMonitorValues)
                if app.BooleanMonitorValues(i)
                    switch i
                        case 1
                            app.FocusReadbackEditField.Value = lcaGet(app.ISSIPVs(1));
                        case 2
                            app.ApertureReadbackEditField.Value = lcaGet(app.ISSIPVs(2));
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, IOCname, Panelname)
            addpath('/usr/local/EPICS_NEW/labca_3_7_2/bin/linux-x86_64/labca/')
            
            app.IOC_name = IOCname;
            app.Panel_name = Panelname;
            
            app.UIFigure.Name = [app.Panel_name ' ISSI Controls'];
            
            app.ISSIPVs = {...
                [app.IOC_name ':FocusValue']
                [app.IOC_name ':ApertureValue']
            };
            
            app.CameraFocusValue = lcaGet([app.IOC_name ':FocusValue']);
            app.FocusReadbackEditField.Value = app.CameraFocusValue;
            app.CameraAperture = lcaGet([app.IOC_name ':ApertureValue']);
            app.ApertureReadbackEditField.Value = app.CameraAperture;
            
            app.FocusReadbackEditField.Editable = 'off';
            app.ApertureReadbackEditField.Editable = 'off';
            
            lcaSetMonitor(app.ISSIPVs(1:2))

            app.ISSItimer = timer('Name', 'ISSItimer', ...
                'Period', 0.01, ...
                'StartDelay', 3, ...
                'TasksToExecute', inf, ...
                'ExecutionMode','FixedRate', ...
                'TimerFcn', @(varargin)CheckMonitors(app));
            
            % starts timer
            t_Timer = app.ISSItimer ;
            start(t_Timer)
        end

        % Button pushed function: GoFocus
        function GoFocusPushed(app, event)
            if app.FocusValueEditField.Value > 16869
                errordlg('You have reached a limit')
            else
                lcaPutNoWait([app.IOC_name ':SetFocus'],app.FocusValueEditField.Value)
            end
        end

        % Button pushed function: LeftFocusButton
        function LeftFocusButtonPushed(app, event)
            newFocus = app.FocusReadbackEditField.Value - app.FocusIncrementEditField.Value;
            if newFocus > 16869
                errordlg('You have reached a limit')
            else
                lcaPutNoWait([app.IOC_name ':TweakFocus'],-1*app.FocusIncrementEditField.Value)
            end
        end

        % Button pushed function: RightFocusButton
        function RightFocusButtonPushed(app, event)
            newFocus = app.FocusReadbackEditField.Value + app.FocusIncrementEditField.Value;
            if newFocus > 16869
                errordlg('You have reached a limit')
            else
                lcaPutNoWait([app.IOC_name ':TweakFocus'],app.FocusIncrementEditField.Value)
            end
        end

        % Button pushed function: LeftApertureButton
        function LeftApertureButtonPushed(app, event)
            lcaPutNoWait([app.IOC_name ':TweakAperture'],-1)
            while true
                if lcaNewMonitorValue([app.IOC_name ':ApertureValue'])
                    break
                end
            end
        end

        % Button pushed function: RightApertureButton
        function RightApertureButtonPushed(app, event)
            lcaPutNoWait([app.IOC_name ':TweakAperture'],1)
            while true
                if lcaNewMonitorValue([app.IOC_name ':ApertureValue'])
                    break
                end
            end
        end

        % Button pushed function: GoPresetButton
        function GoPresetButtonPushed(app, event)
            FocusPresetsTextFileData = readcell([app.IOC_name '_Focus_Presets.txt']);
            app.FocusPresets = [FocusPresetsTextFileData{end,1:(length(FocusPresetsTextFileData)-1)}] ;
            for ii = 1:length(app.FocusPresets)
                if strcmp(app.PresetsDropDown.Value,app.PresetsDropDown.Items(ii))
                    if app.FocusPresets(ii) > 16869
                        errordlg('You have reached a limit')
                    else
                        lcaPutNoWait([app.IOC_name ':SetFocus'],app.FocusPresets(ii))
                    end
                end
            end
        end

        % Menu selected function: EditPresetsMenu
        function EditPresetsMenuSelected(app, event)
            ISSIgui = findall(0, 'Name', [app.Panel_name ' ISSI Edit Presets']);
            if ~isempty(ISSIgui) == 0
                ISSI_edit_presets_test(app.IOC_name, app.Panel_name)
            else
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            clear app.ISSItimer
            stop(app.ISSItimer)
            delete(app.ISSItimer) 
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GoFocus = uibutton(app.UIFigure, 'push');
            app.GoFocus.ButtonPushedFcn = createCallbackFcn(app, @GoFocusPushed, true);
            app.GoFocus.Text = 'Go';
            app.FocusIncrementEditFieldLabel = uilabel(app.UIFigure);
            app.FocusIncrementEditFieldLabel.Text = 'Focus Increment';
            app.FocusIncrementEditField = uieditfield(app.UIFigure, 'numeric');
            app.FocusIncrementEditField.Value = 1;
            app.LeftFocusButton = uibutton(app.UIFigure, 'push');
            app.LeftFocusButton.ButtonPushedFcn = createCallbackFcn(app, @LeftFocusButtonPushed, true);
            app.LeftFocusButton.Icon = 'left_arrow.png';
            app.LeftFocusButton.Text = '';
            app.RightFocusButton = uibutton(app.UIFigure, 'push');
            app.RightFocusButton.ButtonPushedFcn = createCallbackFcn(app, @RightFocusButtonPushed, true);
            app.RightFocusButton.Icon = 'right_arrow.png';
            app.RightFocusButton.Text = '';
            app.FocusReadbackEditFieldLabel = uilabel(app.UIFigure);
            app.FocusReadbackEditFieldLabel.Text = 'Focus Readback';
            app.FocusReadbackEditField = uieditfield(app.UIFigure, 'numeric');
            app.FocusReadbackEditField.ValueDisplayFormat = '%.0f';
            app.ApertureReadbackEditFieldLabel = uilabel(app.UIFigure);
            app.ApertureReadbackEditFieldLabel.Text = 'Aperture Readback';
            app.ApertureReadbackEditField = uieditfield(app.UIFigure, 'numeric');
            app.FocusValueEditFieldLabel = uilabel(app.UIFigure);
            app.FocusValueEditFieldLabel.Text = 'Focus Value';
            app.FocusValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.FocusValueEditField.ValueDisplayFormat = '%.0f';
            app.ISSILensControllerLabel = uilabel(app.UIFigure);
            app.ISSILensControllerLabel.Text = 'ISSI Lens Controller';
            app.LeftApertureButton = uibutton(app.UIFigure, 'push');
            app.LeftApertureButton.ButtonPushedFcn = createCallbackFcn(app, @LeftApertureButtonPushed, true);
            app.LeftApertureButton.Icon = 'left_arrow.png';
            app.LeftApertureButton.Text = '';
            app.RightApertureButton = uibutton(app.UIFigure, 'push');
            app.RightApertureButton.ButtonPushedFcn = createCallbackFcn(app, @RightApertureButtonPushed, true);
            app.RightApertureButton.Icon = 'right_arrow.png';
            app.RightApertureButton.Text = '';
            app.PresetsDropDownLabel = uilabel(app.UIFigure);
            app.PresetsDropDownLabel.Text = 'Presets';
            app.PresetsDropDown = uidropdown(app.UIFigure);
            app.PresetsDropDown.Items = {'P1', 'P2', 'P3', 'P4'};
            app.PresetsDropDown.Value = 'P1';
            app.GoPresetButton = uibutton(app.UIFigure, 'push');
            app.GoPresetButton.ButtonPushedFcn = createCallbackFcn(app, @GoPresetButtonPushed, true);
            app.GoPresetButton.Text = 'Go';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.PresetsDropDown.ContextMenu = app.ContextMenu;
            app.EditPresetsMenu = uimenu(app.ContextMenu);
            app.EditPresetsMenu.MenuSelectedFcn = createCallbackFcn(app, @EditPresetsMenuSelected, true);
            app.EditPresetsMenu.Text = 'Edit Presets';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ISSI_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end