classdef LED_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        LEDstatusSwitchLabel        matlab.ui.control.Label
        LEDstatusSwitch             matlab.ui.control.Switch
        ExposuretimeEditFieldLabel  matlab.ui.control.Label
        ExposuretimeEditField       matlab.ui.control.NumericEditField
        GainEditFieldLabel          matlab.ui.control.Label
        GainEditField               matlab.ui.control.NumericEditField
        SAVEButton                  matlab.ui.control.Button
    end

    
    properties (Access = public)
        MCIOC;
        CameraIOC;
        BrightField;
        DarkField;
        PresetLine;
        ExposureTime;
        Gain;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MCIOC, CameraIOC, Cameraname)
            % File works where first 2 entries are bright field, second 2
            % are dark field. First entry in each pair is exposure time,
            % second entry is gain.
            app.MCIOC = MCIOC;
            app.CameraIOC = CameraIOC;
            app.UIFigure.Name = ['LED ' Cameraname ' Controls'];
            PresetsTextFileData = readcell([app.CameraIOC '_Presets.txt']);
            app.PresetLine = [PresetsTextFileData{end,1:(length(PresetsTextFileData(end,:))-1)}] ;
            app.BrightField = app.PresetLine(1:2);
            app.DarkField = app.PresetLine(3:4);
            lcaPutNoWait([app.CameraIOC ':cam1:AcquireTime'],app.DarkField(1))
            app.ExposuretimeEditField.Value = lcaGet([app.CameraIOC ':cam1:AcquireTime']);
            app.ExposureTime = app.ExposuretimeEditField.Value;
            lcaPutNoWait([app.CameraIOC ':cam1:Gain'],app.DarkField(2))
            app.GainEditField.Value = lcaGet([app.CameraIOC ':cam1:Gain']);
            app.Gain = app.GainEditField.Value;
            if strcmp(lcaGet(app.MCIOC),'High')
                app.LEDstatusSwitch.Value = 'On';
            else
                app.LEDstatusSwitch.Value = 'Off';
            end
        end

        % Value changed function: LEDstatusSwitch
        function LEDstatusSwitchValueChanged(app, event)
            LEDStatus = app.LEDstatusSwitch.Value;
            if strcmp(LEDStatus,'On')
                lcaPutNoWait(app.MCIOC,1)
                app.ExposureTime = app.BrightField(1);
                lcaPutNoWait([app.CameraIOC ':cam1:AcquireTime'],app.ExposureTime)
                app.ExposuretimeEditField.Value = app.ExposureTime;
                app.Gain = app.BrightField(2);
                lcaPutNoWait([app.CameraIOC ':cam1:Gain'],app.Gain)
                app.GainEditField.Value = app.Gain;
            else
                lcaPutNoWait(app.MCIOC,0)
                app.ExposureTime = app.DarkField(1);
                lcaPutNoWait([app.CameraIOC ':cam1:AcquireTime'],app.ExposureTime)
                app.ExposuretimeEditField.Value = app.ExposureTime;
                app.Gain = app.DarkField(2);
                lcaPutNoWait([app.CameraIOC ':cam1:Gain'],app.Gain)
                app.GainEditField.Value = app.Gain;
            end
        end

        % Value changed function: ExposuretimeEditField
        function ExposuretimeEditFieldValueChanged(app, event)
            lcaPutNoWait([app.CameraIOC ':cam1:AcquireTime'],app.ExposuretimeEditField.Value)
            app.ExposureTime = app.ExposuretimeEditField.Value;
        end

        % Value changed function: GainEditField
        function GainEditFieldValueChanged(app, event)
            lcaPutNoWait([app.CameraIOC ':cam1:Gain'],app.GainEditField.Value)
            app.Gain = app.GainEditField.Value;
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            if strcmp(app.LEDstatusSwitch.Value, 'On')
                app.BrightField(1) = app.ExposuretimeEditField.Value;
                app.BrightField(2) = app.GainEditField.Value;
                app.PresetLine(1:2) = app.BrightField; 
            else
                app.DarkField(1) = app.ExposuretimeEditField.Value;
                app.DarkField(2) = app.GainEditField.Value;
                app.PresetLine(3:4) = app.DarkField;
            end
            writtentext = {app.PresetLine,datestr(datetime('now'))};
            writecell(writtentext,[app.CameraIOC '_Presets.txt'],'WriteMode','append')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LEDstatusSwitchLabel = uilabel(app.UIFigure);
            app.LEDstatusSwitchLabel.Text = 'LED status';
            app.LEDstatusSwitch = uiswitch(app.UIFigure, 'slider');
            app.LEDstatusSwitch.ValueChangedFcn = createCallbackFcn(app, @LEDstatusSwitchValueChanged, true);
            app.ExposuretimeEditFieldLabel = uilabel(app.UIFigure);
            app.ExposuretimeEditFieldLabel.Text = 'Exposure time';
            app.ExposuretimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExposuretimeEditField.ValueChangedFcn = createCallbackFcn(app, @ExposuretimeEditFieldValueChanged, true);
            app.GainEditFieldLabel = uilabel(app.UIFigure);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.UIFigure, 'numeric');
            app.GainEditField.ValueChangedFcn = createCallbackFcn(app, @GainEditFieldValueChanged, true);
            app.SAVEButton = uibutton(app.UIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LED_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end